classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MainControlsLabel    matlab.ui.control.Label
        XButton              matlab.ui.control.StateButton
        XButton_2            matlab.ui.control.StateButton
        YButton              matlab.ui.control.StateButton
        YButton_2            matlab.ui.control.StateButton
        ZButton              matlab.ui.control.StateButton
        ZButton_2            matlab.ui.control.StateButton
        RollButton           matlab.ui.control.StateButton
        RollButton_2         matlab.ui.control.StateButton
        PitchButton          matlab.ui.control.StateButton
        PitchButton_2        matlab.ui.control.StateButton
        YawButton            matlab.ui.control.StateButton
        YawButton_2          matlab.ui.control.StateButton
        ESTOPButton          matlab.ui.control.Button
        ConfirmResumeButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        
        %Buttons%
        xpos
        xneg
        ypos
        yneg
        zpos
        zneg
        rpos
        rneg
        ppos
        pneg
        ywpos
        ywneg
        %Buttons%
        
        %Velocity%
        vx
        vy
        vz
        wx
        wy
        wz
        %Velocity%
        
        Kv
        Kw
        
        q6
        q7
        robot
        vectorm
        myTimer
    end
    
    methods (Access = private)
        
        % used in updateCalcs to calculate vectorm
        function vectorCalc(app)
            app.vx = app.Kv*(app.xpos - app.xneg);
            app.vy = app.Kv*(app.ypos - app.yneg);
            app.vz = app.Kv*(app.zpos - app.zneg);
            app.wx = app.Kw*(app.rpos - app.rneg);
            app.wy = app.Kw*(app.ppos - app.pneg);
            app.wz = app.Kw*(app.ywpos - app.ywneg);
            app.vectorm = [app.vx app.vy app.vz app.wx app.wy app.wz];
        end
        
        % used in updateApp to calculate q
        function q = qCalc(app)
            lambda = 0.5;
            J = app.robot.model.jacob0(app.q7); %jacob0 takes q7 and outputs 6x7
            J = J(:,2:7); %dont consider joint 1 (q1)
            
            % DLS J Inverse to calculate joint velocity
            Jinv_dls = inv((J'*J)+lambda^2*eye(6))*J';
            dq = Jinv_dls*app.vectorm';
            
            
            % apply joint velocity to step robot joint angles
            dt = 0.15;
            q = app.q6 + dq'*dt;
            app.q6 = q;
            app.q7 = [app.q7(1) app.q6];
        end
        
        function updateApp(app)
            app.vectorCalc
            app.qCalc
        end
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    methods (Access = public)

        function qOut = read(app)
            % function should be in a loop (keeps running)
            controlm(1) = app.xpos;
            controlm(2) = app.xneg;
            controlm(3) = app.ypos;
            controlm(4) = app.yneg;
            controlm(5) = app.zpos;
            controlm(6) = app.zneg;
            controlm(7) = app.rpos;
            controlm(8) = app.rneg;
            controlm(9) = app.ppos;
            controlm(10) = app.pneg;
            controlm(11) = app.ywpos;
            controlm(12) = app.ywneg;
            
            % when there is a change -> update calculations and animate
            if controlm(1)||controlm(2)||controlm(3)||controlm(4)||controlm(5)||controlm(6)
                app.updateApp
            elseif controlm(7)||controlm(8)||controlm(9)||controlm(10)||controlm(11)||controlm(12)
                app.updateApp
            end
      
            qOut = app.q7;
        end
        
        function getRobot(app, robot)
            % run function after declaring the object
            app.robot = robot;
            robotq = app.robot.model.getpos;
            app.q7 = robotq;
            app.q6 = robotq(2:7);
            app.updateApp;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xpos = 0;
            app.xneg = 0;
            app.ypos = 0;
            app.yneg = 0;
            app.zpos = 0;
            app.zneg = 0;
            app.rpos = 0;
            app.rneg = 0;
            app.ppos = 0;
            app.pneg = 0;
            app.ywpos = 0;
            app.ywneg = 0;
            
            app.vx = 0;
            app.vy = 0;
            app.vz = 0;
            app.wx = 0;
            app.wy = 0;
            app.wz = 0;
            
            app.Kv = 0.5;
            app.Kw = 1;
            
            app.q6 = zeros(1,6);
            app.q7 = zeros(1,7);
            app.vectorm = zeros(1,6);
                 
            app.myTimer = timer('Period',0.05,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.myTimer.TimerFcn = @(~,~)app.updateApp;
            
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.xpos = app.XButton.Value;
        end

        % Value changed function: XButton_2
        function XButton_2ValueChanged(app, event)
            app.xneg = app.XButton_2.Value;
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.ypos = app.YButton.Value;
        end

        % Value changed function: YButton_2
        function YButton_2ValueChanged(app, event)
            app.yneg = app.YButton_2.Value;
        end

        % Value changed function: ZButton
        function ZButtonValueChanged(app, event)
            app.zpos = app.ZButton.Value;
        end

        % Value changed function: ZButton_2
        function ZButton_2ValueChanged(app, event)
            app.zneg = app.ZButton_2.Value;
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            %%%%%%%%%%Include ESTOP stop function%%%%%%%%%%
            app.ConfirmResumeButton.Visible = 'on';
            app.ESTOPButton.Enable = 'off';
            app.ConfirmResumeButton.Enable = 'on';
        end

        % Button pushed function: ConfirmResumeButton
        function ConfirmResumeButtonPushed(app, event)
            %%%%%%%%%%Include ESTOP resume function%%%%%%%%%%
            app.ConfirmResumeButton.Visible = 'off';
            app.ESTOPButton.Enable = 'on';
            app.ConfirmResumeButton.Enable = 'off';
        end

        % Value changed function: RollButton
        function RollButtonValueChanged(app, event)
            app.rpos = app.RollButton.Value;
        end

        % Value changed function: RollButton_2
        function RollButton_2ValueChanged(app, event)
            app.rneg = app.RollButton_2.Value;
        end

        % Value changed function: PitchButton
        function PitchButtonValueChanged(app, event)
            app.ppos = app.PitchButton.Value;
        end

        % Value changed function: PitchButton_2
        function PitchButton_2ValueChanged(app, event)
            app.pneg = app.PitchButton_2.Value;
        end

        % Value changed function: YawButton
        function YawButtonValueChanged(app, event)
            app.ywpos = app.YawButton.Value;
        end

        % Value changed function: YawButton_2
        function YawButton_2ValueChanged(app, event)
            app.ywneg = app.YawButton_2.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainControlsLabel = uilabel(app.UIFigure);
            app.MainControlsLabel.Text = 'Main Controls';
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'state');
            app.XButton_2.ValueChangedFcn = createCallbackFcn(app, @XButton_2ValueChanged, true);
            app.XButton_2.Text = 'X-';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'state');
            app.YButton_2.ValueChangedFcn = createCallbackFcn(app, @YButton_2ValueChanged, true);
            app.YButton_2.Text = 'Y-';
            app.ZButton = uibutton(app.UIFigure, 'state');
            app.ZButton.ValueChangedFcn = createCallbackFcn(app, @ZButtonValueChanged, true);
            app.ZButton.Text = 'Z+';
            app.ZButton_2 = uibutton(app.UIFigure, 'state');
            app.ZButton_2.ValueChangedFcn = createCallbackFcn(app, @ZButton_2ValueChanged, true);
            app.ZButton_2.Text = 'Z-';
            app.RollButton = uibutton(app.UIFigure, 'state');
            app.RollButton.ValueChangedFcn = createCallbackFcn(app, @RollButtonValueChanged, true);
            app.RollButton.Text = 'Roll+';
            app.RollButton_2 = uibutton(app.UIFigure, 'state');
            app.RollButton_2.ValueChangedFcn = createCallbackFcn(app, @RollButton_2ValueChanged, true);
            app.RollButton_2.Text = 'Roll-';
            app.PitchButton = uibutton(app.UIFigure, 'state');
            app.PitchButton.ValueChangedFcn = createCallbackFcn(app, @PitchButtonValueChanged, true);
            app.PitchButton.Text = 'Pitch+';
            app.PitchButton_2 = uibutton(app.UIFigure, 'state');
            app.PitchButton_2.ValueChangedFcn = createCallbackFcn(app, @PitchButton_2ValueChanged, true);
            app.PitchButton_2.Text = 'Pitch-';
            app.YawButton = uibutton(app.UIFigure, 'state');
            app.YawButton.ValueChangedFcn = createCallbackFcn(app, @YawButtonValueChanged, true);
            app.YawButton.Text = 'Yaw+';
            app.YawButton_2 = uibutton(app.UIFigure, 'state');
            app.YawButton_2.ValueChangedFcn = createCallbackFcn(app, @YawButton_2ValueChanged, true);
            app.YawButton_2.Text = 'Yaw-';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E  -  S  T  O  P';
            app.ConfirmResumeButton = uibutton(app.UIFigure, 'push');
            app.ConfirmResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmResumeButtonPushed, true);
            app.ConfirmResumeButton.Enable = 'off';
            app.ConfirmResumeButton.Visible = 'off';
            app.ConfirmResumeButton.Text = 'Confirm Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end